classdef PG_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure             % UI Figure
        UIAxes                  matlab.ui.control.UIAxes     % Title
        dropDownLabel           matlab.ui.control.Label      % Select Load ...
        dropDown                matlab.ui.control.DropDown   % Option 1, Op...
        profileNameLabel        matlab.ui.control.Label      % GraphTitle
        descriptionPanel        matlab.ui.container.Panel    % Load Profile...
        desc_ProfileNameLabel   matlab.ui.control.Label      % Profile Name
        desc_ProfileDescription matlab.ui.control.Label      % Profile Desc...
        descProfileSrcHyperlink matlab.ui.control.Label      % Link to Source
        TabGroup                matlab.ui.container.TabGroup % EconomicMetr...
        economicTab             matlab.ui.container.Tab      % EconomicMetrics
        val_PUCost              matlab.ui.control.Label      % VALUE
        val_kWh                 matlab.ui.control.Label      % VALUE
        val_FixedCost           matlab.ui.control.Label      % VALUE
        val_SemiFixedCost       matlab.ui.control.Label      % VALUE
        puCostLabel             matlab.ui.control.Label      % Per Unit Cost
        kWhLabel                matlab.ui.control.Label      % Total kWh
        fixedCostLabel          matlab.ui.control.Label      % Fixed Cost
        semiFixedCostLabel      matlab.ui.control.Label      % Semi-Fixed
        extraLabel              matlab.ui.control.Label      % Variable
        Val_TBDLabel            matlab.ui.control.Label      % VALUE
        technicalTab            matlab.ui.container.Tab      % TechnicalMet...
        avgLoadLabel            matlab.ui.control.Label      % Avg Load
        peakLoadLabel           matlab.ui.control.Label      % Peak Load
        unitsLabel              matlab.ui.control.Label      % Units Consumed
        maxDemandLabel          matlab.ui.control.Label      % Max Demand
        val_AvgLoad             matlab.ui.control.Label      % VALUE
        val_PeakLoad            matlab.ui.control.Label      % VALUE
        val_Units               matlab.ui.control.Label      % VALUE
        val_maxDemand           matlab.ui.control.Label      % VALUE
    end


    properties (Access = private)
        loadProfileData;         % MATLAB table
        loadProfileNames;        % Cell array of profile names
    end

    methods (Access = private)
        
        function updateLoadProfileGraph(app, profileName)
            plot(app.UIAxes, app.loadProfileData.(profileName));
            app.UIAxes.XLabel.String = 'Time';
            app.UIAxes.YLabel.String = 'Load (kW)';
            app.UIAxes.Title.Visible = 'off';
            app.profileNameLabel.Text = profileName;
            
        end

    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Read the load profiles as a table with column headers specifying profile name
            app.loadProfileData = readtable('../load-profiles/all-load-profiles.xlsx');
            
            % Extract names of load profiles from table
            app.loadProfileNames = app.loadProfileData.Properties.VariableNames;
            
            % Populate dropdown with names of load profiles
            app.dropDown.Items = app.loadProfileNames;
      
            % Plot first load profile by default on startup
            updateLoadProfileGraph(app, app.loadProfileNames{1});
        end

        % dropDown value changed function
        function dropDownValueChanged(app, event)
            % Use name of new load profile to plot data
            updateLoadProfileGraph(app, event.Value);
            
            % Hotfix - limit dropdown width
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.dropDownLabel = uilabel(app.UIFigure);
            app.dropDownLabel.Text = 'Select Load Profile';
            app.dropDown = uidropdown(app.UIFigure);
            app.dropDown.ValueChangedFcn = createCallbackFcn(app, @dropDownValueChanged, true);
            app.profileNameLabel = uilabel(app.UIFigure);
            app.profileNameLabel.Text = 'GraphTitle';
            app.descriptionPanel = uipanel(app.UIFigure);
            app.descriptionPanel.Title = 'Load Profile Description';
            app.descriptionPanel.Units = 'pixels';
            app.desc_ProfileNameLabel = uilabel(app.descriptionPanel);
            app.desc_ProfileNameLabel.Text = 'Profile Name';
            app.desc_ProfileDescription = uilabel(app.descriptionPanel);
            app.desc_ProfileDescription.Text = 'Profile Description';
            app.descProfileSrcHyperlink = uilabel(app.descriptionPanel);
            app.descProfileSrcHyperlink.Text = 'Link to Source';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.economicTab = uitab(app.TabGroup);
            app.economicTab.Units = 'pixels';
            app.economicTab.Title = 'EconomicMetrics';
            app.val_PUCost = uilabel(app.economicTab);
            app.val_PUCost.Text = 'VALUE';
            app.val_kWh = uilabel(app.economicTab);
            app.val_kWh.Text = 'VALUE';
            app.val_FixedCost = uilabel(app.economicTab);
            app.val_FixedCost.Text = 'VALUE';
            app.val_SemiFixedCost = uilabel(app.economicTab);
            app.val_SemiFixedCost.Text = 'VALUE';
            app.puCostLabel = uilabel(app.economicTab);
            app.puCostLabel.Text = 'Per Unit Cost';
            app.kWhLabel = uilabel(app.economicTab);
            app.kWhLabel.Text = 'Total kWh';
            app.fixedCostLabel = uilabel(app.economicTab);
            app.fixedCostLabel.Text = 'Fixed Cost';
            app.semiFixedCostLabel = uilabel(app.economicTab);
            app.semiFixedCostLabel.Text = 'Semi-Fixed';
            app.extraLabel = uilabel(app.economicTab);
            app.extraLabel.Text = 'Variable';
            app.Val_TBDLabel = uilabel(app.economicTab);
            app.Val_TBDLabel.Text = 'VALUE';
            app.technicalTab = uitab(app.TabGroup);
            app.technicalTab.Units = 'pixels';
            app.technicalTab.Title = 'TechnicalMetrics';
            app.avgLoadLabel = uilabel(app.technicalTab);
            app.avgLoadLabel.Text = 'Avg Load';
            app.peakLoadLabel = uilabel(app.technicalTab);
            app.peakLoadLabel.Text = 'Peak Load';
            app.unitsLabel = uilabel(app.technicalTab);
            app.unitsLabel.Text = 'Units Consumed';
            app.maxDemandLabel = uilabel(app.technicalTab);
            app.maxDemandLabel.Text = 'Max Demand';
            app.val_AvgLoad = uilabel(app.technicalTab);
            app.val_AvgLoad.Text = 'VALUE';
            app.val_PeakLoad = uilabel(app.technicalTab);
            app.val_PeakLoad.Text = 'VALUE';
            app.val_Units = uilabel(app.technicalTab);
            app.val_Units.Text = 'VALUE';
            app.val_maxDemand = uilabel(app.technicalTab);
            app.val_maxDemand.Text = 'VALUE';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PG_App()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end